classdef draw_rois_gui_v6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        preROIdir                    matlab.ui.control.EditField
        PreROIButton                 matlab.ui.control.Button
        PlotAllExternallyButton      matlab.ui.control.Button
        testbuttonButton             matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        LoadTab                      matlab.ui.container.Tab
        PatNumEditField              matlab.ui.control.NumericEditField
        STDofFiltEditFieldLabel      matlab.ui.control.Label
        STDofFiltEditField           matlab.ui.control.NumericEditField
        FilterImagesLoadButton       matlab.ui.control.Button
        LoadPatBut                   matlab.ui.control.Button
        EchoEnd                      matlab.ui.control.NumericEditField
        EndEchoLabel                 matlab.ui.control.Label
        EchoStart                    matlab.ui.control.NumericEditField
        StartEchoEditFieldLabel      matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        DrawROITab                   matlab.ui.container.Tab
        MoveableCheckBox             matlab.ui.control.CheckBox
        FormatROIsButton             matlab.ui.control.Button
        ToggleAllROIVisButton        matlab.ui.control.Button
        LoadPrevButton               matlab.ui.control.Button
        VisROICheckBox               matlab.ui.control.CheckBox
        SaveROIsButton               matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        CancelButton                 matlab.ui.control.Button
        FinishROIButton              matlab.ui.control.Button
        RemoveROIButton              matlab.ui.control.Button
        DrawROIButton                matlab.ui.control.Button
        SliceSelectedEditField       matlab.ui.control.NumericEditField
        SliceSelectedEditFieldLabel  matlab.ui.control.Label
        SliceSlider                  matlab.ui.control.Slider
        debugtogglevisButton         matlab.ui.control.Button
        EchoSelectedEditField        matlab.ui.control.NumericEditField
        EchoSelectedLabel            matlab.ui.control.Label
        ReInitializeGUIButton        matlab.ui.control.Button
        Slider                       matlab.ui.control.Slider
        T2datadir                    matlab.ui.control.EditField
        T2DataFolderButton           matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Draw Roi Gui
    %   To be able to draw ROIs on the image. Made for segmenting glandular
    %   regions of the prostate MRI for LWI
    % GUI V6 (May 28, 2021)
    %   - included a moveable ticker:'m' to switch moveability of ROIs
    %   - included key press 'q' to toggle all 'O' ROIs for certain silce
    %   - included key press 'w' to toggle all 'PZ' ROIS for slice
    %   - included key press 'e' to toggle all 'TZ' ROIS for slice
    %   - included key press 'r' to toggle all 'CZ' ROIS for slice
    %
    % GUI V5 (May 27, 2021)
    %   - included key press of 'a' and 'd' for left right scrolling of
    %     echo
    %   - included key press of 'left' and 'right' arrow for left and right
    %     scrolling of slices
    %   - included key press of 'v' to switch visibility of ROIs
    %
    % GUI V4 (May 21st, 2021)
    %   - removing Enable Axis checkbox
    %   - fixed bugs regarding visibility toggling
    %   - included loading in ROIs from parametric maps (for purposes of
    %     outlining already analyzed data!
    %   - included loading button of previous data
    %   - included a roi visualize all button
    % GUI V4-2 (May 24th, 2021)
    %   - included intersect plotting
    %   - preload rois
    %
    % GUI V3 (May 18st, 2021)
    %   - including a plot all button, and removing the ROI folder
    %   - including a disable default axis interactivity checkbox
    %
    % GUI V2 (May 15st, 2021)
    %   - using T2 decay data to do segmentation
    %  r2
    %   - including extra tab
    %
    % GUI V1 (May 13st, 2021)
    %   - used LWI data
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%  Private Properties - Accessible within the App but not publicly in Matlab
    properties (Access = private)
        slices                  % number of slices usable with LWI parameters for a particular patient
            %                          slices.num
            %                          slices.pat 
        patT2data               % the T2 data of the patient, likely 240 x 240 x 14 x 64
        imData                  % the image data being plotted for a patient
        curSlice                % the current slice being shown
        curEcho                 % the current echo being shown
        firstPatDataInit        % flag for the when new patient data is being loaded
        polyH                   % handle for the ROI polygon
        roiData                 % the ROI data plotted
        roiColour               % colour of the ROIs 
        curTableInd             % current index being selected in table
        imPreROIData              % the data of the ROI that outlines the prostate (if available)
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    methods (Access = private)
        
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Initializing function
        % -----------------------------------
        function initializingGUI(app)
            % Initializing private app properties
            app.slices = {};
            app.patT2data = {};
            app.imData = {};
            app.curSlice = [];
            app.curEcho = [];
            app.firstPatDataInit = -1;
            app.polyH = [];
            app.roiData = {};
            app.curTableInd = [];
            app.imPreROIData = [];
            
            % Initializing UIAxes
            delete(app.UIAxes.Children)
            app.UIAxes.Clipping = 'on';
            app.UIAxes.ClippingStyle = 'rectangle';
            disableDefaultInteractivity(app.UIAxes)
            
            % Initializing directories
            app.T2datadir.Value = '';
            
            % Initializing list box to be blank
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            
            % initializing Load Tab
            app.EchoSelectedEditField.Value = 1;
            app.EchoStart.Value = 1;
            app.EchoEnd.Value = 1;
            app.PatNumEditField.Value = 0;
            
            % initializing slider values
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0:10];
            app.Slider.MinorTicks = [0:10];
            app.SliceSlider.Limits = [0 10];
            app.SliceSlider.MajorTicks = [0:10];
            app.SliceSlider.MinorTicks = [0:10];
            
            % Initializing table
            app.UITable.Data = {};
            
            % Initializing ROI colours
            app.roiColour{1} = [211,211,211]./255; % None - grey
            app.roiColour{2} = [30,144,255]./255; % Overall - blue
            app.roiColour{3} = [220,20,60]./255; % PZ - red
            app.roiColour{4} = [255,255,102]./255; % TZ - yellow
            app.roiColour{5} = [255,140,0]./255; % CZ - orange
            app.roiColour{6} = [147,112,219]./255; % preROI colour - purple
            
            % toggling everything off
            toggleVis(app,0);
            % ROI tab toggling
            toggleVisDrawingROI(app,-1)
            app.T2DataFolderButton.Enable = true;
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Removing all images from children in UIAxes
        % Also removing all pre-image ROIs associated with the patient
        % -----------------------------------
        function rmvImages(app)
            delcount = zeros(numel(app.UIAxes.Children),1);
            for i = 1:numel(app.UIAxes.Children) % looping through all objects in uiaxes
                if isa(app.UIAxes.Children(i),'matlab.graphics.primitive.Image') % if the object is an image, remove
                    delcount(i) = 1;
                end                
            end
            delete(app.UIAxes.Children(find(delcount))); % deleting
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
        
        
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Toggles app function buttons on or off
        % -----------------------------------
        function toggleVis(app,val)
            app.EchoStart.Enable = val;
            app.EchoEnd.Enable = val;
            app.EchoSelectedEditField.Enable = val;
            app.SliceSelectedEditField.Enable = val;
            app.Slider.Enable = val;
            app.SliceSlider.Enable = val;
            app.LoadPatBut.Enable = val;   
            app.FilterImagesLoadButton.Enable = val;
            app.STDofFiltEditField.Enable = val;
            app.SaveROIsButton.Enable = val;
            app.T2DataFolderButton.Enable = val;
            app.PreROIButton.Enable = val;
            app.ReInitializeGUIButton.Enable = val;
            app.PlotAllExternallyButton.Enable = val;
            app.LoadPrevButton.Enable = val;
            app.ToggleAllROIVisButton.Enable = val;
            app.FormatROIsButton.Enable = val;
            
            if val == 0
                app.UITable.Enable = 'off';
            else
                app.UITable.Enable = 'on';
            end
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
        
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Toggles enable-ability for ROI drawing
        %   - three states possible
        %   -   -1 = default off
        %   -    0 = resting state, but data loaded
        %   -    1 = drawing in progress
        % -----------------------------------
        function toggleVisDrawingROI(app,val)
            switch val
                case -1 % default off
                    app.DrawROIButton.Enable = 0;
                    app.RemoveROIButton.Enable = 0;
                    app.FinishROIButton.Enable = 0;             
                    app.CancelButton.Visible = 0;            
                    app.CancelButton.Enable = 0;
                    app.VisROICheckBox.Enable = 1;
                    app.MoveableCheckBox.Enable = 0;
                case 0 % resting state, but data loaded
                    app.DrawROIButton.Enable = 1;
                    app.RemoveROIButton.Enable = 1;
                    app.FinishROIButton.Enable = 0;            
                    app.CancelButton.Visible = 0;            
                    app.CancelButton.Enable = 0;
                    app.VisROICheckBox.Enable = 1;
                    app.MoveableCheckBox.Enable = 1;
                case 1 % drawing in progress
                    app.DrawROIButton.Enable = 0;
                    app.RemoveROIButton.Enable = 0;
                    app.FinishROIButton.Enable = val;             
                    app.CancelButton.Visible = val;            
                    app.CancelButton.Enable = val;
                    app.VisROICheckBox.Enable = val;
                    app.MoveableCheckBox.Enable = val;
            end

        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
       
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Update Plot function (ROI on) based on various slices/echoes
        % -----------------------------------
        function updateFig(app,ROIon)
            if app.firstPatDataInit
                app.firstPatDataInit = 0; % need to 
            else
                % turning off current image
                app.imData{app.curSlice}{app.curEcho}.Visible = 'off';
                if ~isempty(app.imPreROIData)
                    if ~isempty(app.imPreROIData{app.curSlice}) && isvalid((app.imPreROIData{app.curSlice}))
                        app.imPreROIData{app.curSlice}.Visible = 'off';
                    end
                end
            end
            
            % getting current slice / echo
            app.curSlice = app.SliceSelectedEditField.Value;
            app.curEcho = app.EchoSelectedEditField.Value;
            % making current slice / echo visible
            app.imData{app.curSlice}{app.curEcho}.Visible = 'on';
            % making roi's edges visible
            if ~isempty(app.imPreROIData)
                if ~isempty(app.imPreROIData{app.curSlice}) && isvalid((app.imPreROIData{app.curSlice}))
                    if ROIon 
                        app.imPreROIData{app.curSlice}.Visible = 'on';
                    else
                        app.imPreROIData{app.curSlice}.Visible = 'off';
                    end
                end
            end
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
        
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Update the echo range
        % -----------------------------------
        function UpdateEchoRange(app)
            % Initializing values
            maxEcho = app.EchoEnd.Value;
            minEcho = app.EchoStart.Value;
            
            
            if ~isempty(app.patT2data)
                greatestEcho = size(app.patT2data,4);
            else
                greatestEcho = 64;
            end
            if minEcho < 1 || maxEcho > greatestEcho
                errordlg('Please specify a valid range. Start echo must be greater than 1 and end echo cannot be larger than 64.')
                % initializing echo box
                app.EchoSelectedEditField.Value = 1;
                app.EchoStart.Value = 1;
                app.EchoEnd.Value = 1;
                SliderValueChanged(app);
                
            elseif minEcho < maxEcho
            
                % updating the number of echoes on scroll bar
                app.Slider.Limits = [minEcho maxEcho];
                
                numticks = maxEcho-minEcho+ 1;
                maxTicks = 10;
                if numticks > maxTicks
                    subticks = floor(numticks / maxTicks);
                    newticks = minEcho:subticks:maxEcho;
                    if ~(maxEcho==newticks(end)) 
                        if (maxEcho-newticks(end))>2
                            newticks = [newticks maxEcho];
                        else
                            newticks(end) = maxEcho;
                        end
                    end
                else
                    newticks = minEcho:maxEcho;
                end
                
                app.Slider.MajorTicks = newticks;
                app.Slider.MinorTicks = minEcho:maxEcho;
                app.Slider.Value = minEcho;
                SliderValueChanged(app);
                
            elseif minEcho == maxEcho % code if only one echo selected
                app.Slider.MajorTicks = minEcho;
                
                app.Slider.Value = minEcho;
                
                SliderValueChanged(app);
                
            else
                errordlg('Please specify a valid range. The start echo value must be less than end echo value.')
            end
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
        
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Update the echo range
        % -----------------------------------
        function UpdateSliceRange(app)
            minSlice = 1;
            if exist(app.patT2data,'var')
                maxSlice = size(app.patT2data,3);
            else
                maxSlice = 14;
            end
            % updating the number of slices on scroll bar
            app.SliceSlider.Limits = [minSlice maxSlice];
            app.SliceSlider.MajorTicks = minSlice:maxSlice;
            app.SliceSlider.MinorTicks = minSlice:maxSlice;
            app.SliceSlider.Value = minSlice;
            SliceSliderValueChanged(app);
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
         
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Initializes plotting images
        % -----------------------------------
        function plotT2(app)
            toggleVis(app,0) % temporarily remove ability to change values
            pause(0.1)
            
            % re-initializing
            app.imData = {};
            
            % plotting images
            % for wait bar
            loadbar = size(app.patT2data,3)*((app.EchoEnd.Value) - (app.EchoStart.Value)+1);
            w = waitbar(0,'Loading Data');
            i = 1;
            for sliceN = 1:size(app.patT2data,3)
                for echoN = (app.EchoStart.Value):(app.EchoEnd.Value)
                    % update waitbar
                    if(isvalid(w))
                        waitbar(i/loadbar,w);
                        i = i+1;
                    end
                    
                    % plotting images
                    app.imData{sliceN}{echoN} = imshow(app.patT2data(:,:,sliceN,echoN),[],'Parent',app.UIAxes);
                    app.imData{sliceN}{echoN}.Visible = 'off';
                end
            end
            if isvalid(w)
                close(w)
            end
            
            toggleVis(app,1) % bring back ability to change values
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
        
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Plots the pre-outlined ROIs for aiding in outlining PZ
        % -----------------------------------
        function plotPreROI(app)
            if ~isempty(app.preROIdir.Value)
                toggleVis(app,0) % temporarily remove ability to change values
                pause(0.1)
                
                % Finding the im
                patNum = app.PatNumEditField.Value;
                
                % plotting images
                % for wait bar
                loadbar = size(app.patT2data,3)*((app.EchoEnd.Value) - (app.EchoStart.Value)+1);
                w = waitbar(0,'Loading ROI Data');
                i = 1;
                for sliceN = 1:size(app.patT2data,3)
                    getROIs = dir(([app.preROIdir.Value '/*Patient' num2str(patNum) '_*Slice' num2str(sliceN) '_*ROI*.mat']));
                    
                    % update waitbar
                    if(isvalid(w))
                        waitbar(i/loadbar,w);
                        i = i+1;
                    end
                    
                    if ~isempty(getROIs)
                        % load ROI data
                        roi = load([getROIs(1).folder '/' getROIs(1).name]);
                        roi = ~isnan(roi.handles.Parameter_Maps{1});
                        edgeROIdata = imdilate(roi,strel('sphere',1)) - imerode(roi,strel('sphere',1));
                        edgeData = zeros([size(edgeROIdata),3]); 
                        edgeData(:,:,1) = edgeROIdata*app.roiColour{6}(1); 
                        edgeData(:,:,2) = edgeROIdata*app.roiColour{6}(2);
                        edgeData(:,:,3) = edgeROIdata*app.roiColour{6}(3);
                        
                        % plotting images
                        app.imPreROIData{sliceN} = image(edgeData, 'Parent',app.UIAxes);
                        app.imPreROIData{sliceN}.AlphaData = 0.3;
%                         app.imPreROIData{sliceN}.
                        app.imPreROIData{sliceN}.Visible = 'off';
                    
                    else
                        app.imPreROIData{sliceN} = {};
                    end
                    
                end
                if isvalid(w)
                    close(w)
                end
                
                toggleVis(app,1) % bring back ability to change values
            end
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
        
        % ################################################ 
        % -----------------------------------
        % ***************************
        % Plotting images with histogram weighting contrast
        % -----------------------------------
        function plotT2_hist(app)
            toggleVis(app,0) % temporarily remove ability to change values
            pause(0.1)
            
            % re-initializing
            app.imData = {};
            
            % getting the maximum value in the image histogram (but cutting
            % off outliers)
            tempDataAll = app.patT2data(:);
            stdAmt = app.STDofFiltEditField.Value;
            indInRange = ((tempDataAll - mean(tempDataAll) <std(tempDataAll)*stdAmt) & ((tempDataAll - mean(tempDataAll)) > -std(tempDataAll)*stdAmt));
            maxVal = max(tempDataAll(indInRange));
            
            % plotting images
            loadbar = size(app.patT2data,3)*((app.EchoEnd.Value) - (app.EchoStart.Value));
            w = waitbar(0,'Loading Data');
            i = 1;
            for sliceN = 1:size(app.patT2data,3) 
                for echoN = (app.EchoStart.Value):(app.EchoEnd.Value)
                    if isvalid(w)
                        % update waitbar
                        waitbar(i/loadbar,w);
                        i = i+1;
                    end
                    
                    % plotting images
                    app.imData{sliceN}{echoN} = imshow(app.patT2data(:,:,sliceN,echoN)/maxVal,'Parent',app.UIAxes);
                    app.imData{sliceN}{echoN}.Visible = 'off';
                end
            end
            if isvalid(w)
                close(w)
            end
            
            toggleVis(app,1) % bring back ability to change values
        end
        % -----------------------------------
        % ################################################
        % ################################################
        % -----------------------------------
        % Toggles app function buttons on or off
        % -----------------------------------
        function toggleVisAll(app,val)
            toggleVis(app,val);
            % Draw ROI tab
            app.FinishROIButton.Enable = val; 
            app.DrawROIButton.Enable = val;
            app.CancelButton.Visible = val;
            app.CancelButton.Enable = val;
            app.RemoveROIButton.Enable = val;
            app.SaveROIsButton.Enable = val;
            app.VisROICheckBox.Enable = val;
            app.MoveableCheckBox.Enable = val;
        end
        % -----------------------------------
        % ################################################
       
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Update the echo range
        % -----------------------------------
        function updateMoveable(app)
            if ~isempty(app.roiData)
                
                w = waitbar(0,'Updating echo range');
                % looping through all poly's
                switch app.MoveableCheckBox.Value
                    case 1 % turn on moveable
                        for i = 1:numel(app.roiData)
                            app.roiData{i}.InteractionsAllowed='all';
                            if isvalid(w)
                                % update waitbar
                                waitbar(i/numel(app.roiData),w);
                                i = i+1;
                            end
                        end
                        
                    case 0 % turn off moveable
                        for i = 1:numel(app.roiData)
                            app.roiData{i}.InteractionsAllowed='reshape';
                            if isvalid(w)
                                % update waitbar
                                waitbar(i/numel(app.roiData),w);
                                i = i+1;
                            end
                        end
                end
            end
            % close waitbar
            if isvalid(w)
                close(w)
            end
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
        
        % % % % % % % % % % % % % % % % % % % 
        % -----------------------------------
        % Make certain ROIs visible by a key press
        % -----------------------------------
        function roiVisible(app,letter)
            if ~isempty(app.UITable.Data)
                % initializing tabV which will be used to determine which
                % ROIs to loop through and make visible or not
                tabV = [0 0 0 0];
                switch letter
                    case 'q'
                        tabV = [1 0 0 0];
                    case 'w'
                        tabV = [0 1 0 0];
                    case 'e'
                        tabV = [0 0 1 0];
                    case 'r'
                        tabV = [0 0 0 1];
                end
                % getting relevant indices and slice related to the roi to turn on/off
                indices = find(sum(app.UITable.Data{:,3:end}&logical(tabV),2));
                slice = app.SliceSelectedEditField.Value;
                
                %loop through relevant indices 
                for i = 1:numel(indices)
                    nameofROI = strsplit(app.UITable.Data.Var2{indices(i)},{'Slice',' #'}); % getting name of ROI
                    if slice == str2double(nameofROI{2})
                        app.UITable.Data.Var1(indices(i)) = ~app.UITable.Data.Var1(indices(i)); % toggling table value
                        app.roiData{indices(i)}.Visible = logical(app.UITable.Data.Var1(indices(i))); % toggling visibility of ROI
                    end
                        
                end
            end
        end
        % -----------------------------------
        % % % % % % % % % % % % % % % % % % % 
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: T2DataFolderButton
        function T2DataFolderButtonPushed(app, event)
            getdir = uigetdir();
            
            if isempty(app.T2datadir.Value)
                % Initializing only if there hasn't been a selected folder
                % before
                initializingGUI(app)
            end
            
            %A work around to pop the app in front after calling "uigetfile()". This is a current bug in appDesigner
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            if (~isempty(getdir))
                if(getdir == 0)
                    % do nothing
                elseif(~isempty(dir(([getdir '/*.mat'])))) % checking to see if the folder has the .mat files inside (of the LWI data)
                    app.T2datadir.Value = getdir; % saving the folder path
                    
                    % getting info about the slices for the patient
                    files = dir(([getdir '/*.mat']));
                    % initialization
                    slice = zeros(numel(files),1);
                    app.slices.files = [];
                    listboxnames = {};
                    
                    % looping to store information
                    for i = 1:numel(files)
                        app.slices.files{i} = [files(i).folder '/' files(i).name]; % adding the folder/file location
                        temp = strsplit(files(i).name,{'P','_cmplx'});
                        slice(i) = str2double(temp{2});
                        listboxnames{i} = temp{2}; % getting names to enter in the listbox
                    end
                    app.slices.num = numel(files); % adding the number of slices
                    app.slices.list = slice; % adding the slice numbers 
                    
                    % updating list box
                    app.ListBox.Items = listboxnames;
                    app.ListBox.Value = listboxnames{1};
                    
                    % updating start and echo times
                    if exist(app.patT2data,'var')
                        app.EchoEnd.Value = size(app.patT2data,4);                        
                    else
                        app.EchoEnd.Value = 64;
                    end
                    app.EchoEnd.Value = 64;
                    app.EchoStart.Value = 1;
                    UpdateEchoRange(app)
                    UpdateSliceRange(app)
                    SliderValueChanged(app)
                    SliceSliderValueChanged(app)
                    
                    % toggling everything off
                    toggleVis(app,1);
                    
                else
                    
                    m = msgbox('The folder selected must have .mat files containing the T2 decay data.');
                    m.Visible = 'off';
                    pause(0.1)
                    m.Visible = 'on';
                end
            end
        end

        % Button pushed function: ReInitializeGUIButton
        function ReInitializeGUIButtonPushed(app, event)
            qans = questdlg('Do you want to re-initialize the app?','Question Dialog','Yes','No','No');
        
            switch qans
                case 'Yes'
                    % cancelling all the info since the data was incorrect
                    initializingGUI(app)
                    app.T2datadir.Value = '';
                case 'No'
                    % do nothing
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            val = round(app.Slider.Value);
            
            app.EchoSelectedEditField.Value = val; % update the echo selected field
            app.Slider.Value = val; % rounding slider value 
            
            % update figure
            if ~isempty(app.imData)
%                 toggleVis(app,0) 
                updateFig(app, app.VisROICheckBox.Value) % update figure
%                 pause(0.05) % to take a breather
%                 toggleVis(app,1)
            end
            
        end

        % Button pushed function: LoadPatBut
        function LoadPatButPushed(app, event)
            % first time init
            app.firstPatDataInit = 1;
            
            % loading range of echos
            UpdateEchoRange(app)
            
            % Loading pat number in the app
            patNum = app.ListBox.Value; % this is a string value
            % Grabbing the file structure of the selected patient
            filestruct = dir([app.T2datadir.Value '/*' patNum '*.mat']); 
            
            % getting the patient T2 decay data (a 4D matrix)
            tempdata = load([filestruct.folder '/' filestruct.name]);
            app.patT2data = tempdata.cmplx;
            
            % changing edit field
            app.PatNumEditField.Value = str2double(patNum);
            
            % plotting images
            app.UIAxes.NextPlot = 'add'; % making UIAxes an additive plot, so not to delete added plots
            toggleVisAll(app,0) % temporarily disable buttons
            toggleVisDrawingROI(app,-1) % enable ROI buttles
            rmvImages(app) % removing other images from plot
            plotT2(app); % plotting
            plotPreROI(app); % the outline for pre-segmented ROIs
            updateFig(app,app.VisROICheckBox.Value) % update figure
            toggleVisAll(app,1) % renable buttons
            toggleVisDrawingROI(app,0) % enable ROI buttles
            
        end

        % Value changed function: EchoSelectedEditField
        function EchoSelectedEditFieldValueChanged(app, event)
            val = app.EchoSelectedEditField.Value;
            minEcho = app.EchoStart.Value;
            maxEcho = app.EchoEnd.Value;
            
            if val<=maxEcho && val >= minEcho
                app.Slider.Value = val; % updating slider
            else
                app.EchoSelectedEditField.Value = minEcho;
                app.Slider.Value = minEcho;
                errordlg('Echo Selected must exist between viable range.')
            end
           
            
        end

        % Button pushed function: debugtogglevisButton
        function debugtogglevisButtonPushed(app, event)
            currentVis = app.LoadPatBut.Enable;
            
            toggleVisAll(app,~currentVis) 
        end

        % Button pushed function: FilterImagesLoadButton
        function FilterImagesLoadButtonPushed(app, event)
            % first time init
            app.firstPatDataInit = 1;
            
            % loading range of echos
            UpdateEchoRange(app)
            
            % Loading pat number in the app
            patNum = app.ListBox.Value; % this is a string value
            % Grabbing the file structure of the selected patient
            filestruct = dir([app.T2datadir.Value '/*' patNum '*.mat']); 
            
            % getting the patient T2 decay data (a 4D matrix)
            tempdata = load([filestruct.folder '/' filestruct.name]);
            app.patT2data = tempdata.cmplx;
            
            % changing edit field
            app.PatNumEditField.Value = str2double(patNum);
            
            % plotting images
            app.UIAxes.NextPlot = 'add'; % making UIAxes an additive plot, so not to delete added plots
            toggleVisAll(app,0) % temporarily disable buttons
            toggleVisDrawingROI(app,-1) % enable ROI buttles
            rmvImages(app) % removing other images from plot
            plotT2_hist(app); % plotting
            plotPreROI(app);
            updateFig(app,app.VisROICheckBox.Value) % update figure
            toggleVis(app,1) % renable buttons
            toggleVisDrawingROI(app,0) % enable ROI buttles
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            val = round(app.SliceSlider.Value);
            
            app.SliceSelectedEditField.Value = val; % update the echo selected field
            app.SliceSlider.Value = val; % rounding slider value 
            
            % update figure
            if ~isempty(app.imData)
%                 toggleVis(app,0) 
                updateFig(app,app.VisROICheckBox.Value) % update figure
%                 pause(0.05) % to take a breather
%                 toggleVis(app,1)
            end
            
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            % Initializing
            toggleVis(app,0);
            toggleVisDrawingROI(app,-1)
            app.DrawROIButton.Enable = 0;
            app.CancelButton.Visible = 1;
            app.CancelButton.Enable = 0;
            
            % Drawing the ROI
            app.polyH = drawpolygon(app.UIAxes,'Color',[119,136,153]/255,'LineWidth',1,'MarkerSize',3.5,'InteractionsAllowed','all','Selected',1);

            % Toggling button/app visibility for drawing ROI
            toggleVisDrawingROI(app,1); 
            app.polyH.Color = app.roiColour{1}; % changing back to default colour
        end

        % Button pushed function: testbuttonButton
        function testbuttonButtonPushed(app, event)
            %donothing
            disp('testing');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % deleting the polyH handle
            delete(app.polyH);
            
            toggleVisDrawingROI(app,0) % enable ROI buttles
            toggleVis(app,1);
        end

        % Button pushed function: FinishROIButton
        function FinishROIButtonPushed(app, event)
                       
            app.roiData{end+1} = app.polyH;
            
            % New addition to the table
            nameofROI = ['P' num2str(app.PatNumEditField.Value) ' Slice' num2str(app.SliceSelectedEditField.Value)];
            % Getting current names
            if ~isempty(app.UITable.Data)
                curRoiNames = app.UITable.Data.Var2; % getting current names in table
                lastOne = sort(curRoiNames(contains(curRoiNames,nameofROI))); % which is the most latest ROI
                if ~isempty(lastOne)
                    laststrnum = strsplit(lastOne{end},'#'); lastnum = str2double(laststrnum{end});
                    nameofROI = [nameofROI ' #' num2str(lastnum+1)]; % adding name
                else
                    nameofROI = [nameofROI ' #1']; % adding name
                end
            else
                nameofROI = [nameofROI ' #1']; % adding name
            end
            
            
            ROIstate = logical([0 0 0 0]);
            
            if isempty(app.UITable.Data) % adding first file
                app.UITable.Data = table(true, {nameofROI},false,false,false,false);
            else % adding entries when some are already present
                app.UITable.Data = table([app.UITable.Data.Var1; true],[app.UITable.Data.Var2; {nameofROI}],[app.UITable.Data.Var3; ROIstate(1)],[app.UITable.Data.Var4; ROIstate(2)],[app.UITable.Data.Var5; ROIstate(3)],[app.UITable.Data.Var6; ROIstate(4)]);
            end
            
            % Toggling button/app visibility for drawing ROI
            toggleVisDrawingROI(app,0); 
            toggleVis(app,1);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
           
            % change visibility
            if indices(2) == 1 
                app.roiData{indices(1)}.Visible = logical(newData);
             
            % change colours
            elseif indices(2) == 2
                %do nothing                
            else
                % replacing data in table
                newTData = [false false false false]; newTData(indices(2)-2) = logical(newData);
                app.UITable.Data{indices(1),3:end} = newTData;
                                
                % Colouring the ROI
                if newData
                    app.roiData{indices(1)}.Color = app.roiColour{indices(2)-1}; % colour based on click of zone
                else
                    app.roiData{indices(1)}.Color = app.roiColour{1}; % none colour
                end  
            end
            
        end

        % Button pushed function: RemoveROIButton
        function RemoveROIButtonPushed(app, event)
            
            % only remove if the table is not empty
            if ~isempty(app.UITable.Data)
                if ~(sum(app.curTableInd) == 0)
                    indices = sortrows(app.curTableInd,'descend');
                    if size(app.curTableInd,1)>1
                        for i = 1:size(app.curTableInd,1)
                            app.roiData{indices(i,1)}.Visible = false; % removing visibility
                            delete(app.roiData{indices(i,1)});
                        end
                    else
                        app.roiData{indices(1)}.Visible = false; % removing visibility
                        delete(app.roiData{indices(1)});
                    end
                    app.roiData(indices(:,1)) = []; % Removing from roiData dataset
                    app.UITable.Data(indices(:,1),:) = []; % removing from table
                    
                    % updating currently selected
                    app.curTableInd = [];
                    
                end
                
            end
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % getting the current box being selected
            app.curTableInd = event.Indices;
        end

        % Button pushed function: SaveROIsButton
        function SaveROIsButtonPushed(app, event)
            % Select folder to save data
            [savepath] = uigetdir(app.T2datadir.Value);
            
            % if valid savepath, then proceed with saving
            if (~isempty(savepath))
                if(savepath == 0)
                    %do nothing, user cancelled
                else
                    % getting the image size
                    s = size(app.patT2data);
                    s = s(1:2);


                    % making new folder
                    foldername = ['P' num2str(app.PatNumEditField.Value)];
                    if exist([savepath '/' foldername],'dir')
                        numROIfolds = numel(dir([savepath '/P' num2str(app.PatNumEditField.Value) '*']));
                        foldername = ['/P' num2str(app.PatNumEditField.Value) '_(' num2str(numROIfolds) ')'];
                    end
                    mkdir([savepath '/' foldername]);
                    mkdir([savepath '/' foldername '/Mat_Data']);
                    mkdir([savepath '/' foldername '/PNG_Data']);
                    roi_names = cell(size(app.UITable.Data,1),1);
                    
                    
                    % looping through each table value
                    for i = 1:size(app.UITable.Data,1)
                        % getting the current ROI as a mask
                        roi_mask = poly2mask(app.roiData{i}.Position(:,1),app.roiData{i}.Position(:,2),s(1),s(2));
                        
                        % Getting the file name to save the ROI as
                        sliceStrNum = strsplit(app.UITable.Data.Var2{i},{' ','P','Slice','#'});
                        filename = ['Patient' sliceStrNum{2} '_S' sliceStrNum{3} '_ROI']; % getting the filename
                        numSame = numel(dir([savepath '/' foldername '/Mat_Data/' filename '*'])); % calculating how many files are named the same
                        
                        if isempty(find(app.UITable.Data{i,3:end}))
                                typeRoi = 'Unassigned';                        
                        elseif find(app.UITable.Data{i,3:end}) == 1
                                typeRoi = 'ProstateRoi';                            
                        elseif find(app.UITable.Data{i,3:end}) == 2
                                typeRoi = 'PZ';                            
                        elseif find(app.UITable.Data{i,3:end}) == 3
                                typeRoi = 'TZ';                            
                        elseif find(app.UITable.Data{i,3:end}) == 4
                                typeRoi = 'CZ';                            
                        end
                        
                        
                        fullfilenamemat = [savepath '/' foldername '/Mat_Data/' filename num2str(numSame+1) '_' typeRoi '.mat']; % getting the full file name (with correct numbering)
                        fullfilenamepng = [savepath '/' foldername '/PNG_Data/' filename num2str(numSame+1) '_' typeRoi '.png']; % getting the full file name (with correct numbering)]
                        % Saving data
                        save(fullfilenamemat,'roi_mask');
                        imwrite(roi_mask, fullfilenamepng);
                        roi_names{i} = [app.UITable.Data.Var2{i} '_' typeRoi];
                        
                    end
                    % also saving the polygon objects
                    roiPolys.Data = app.roiData; 
                    roiPolys.Names = roi_names;
                    save([savepath '/' foldername '/PolygonDataAll.mat'],'roiPolys');
                end
            end
        end

        % Button pushed function: PlotAllExternallyButton
        function PlotAllExternallyButtonPushed(app, event)
            % plots to an external figure in Matlab
            
            % optimal slices?
            slice = 8;
            echo = 4;
            
            toggleVis(app,0) % temporarily remove ability to change values
            pause(0.1)
            % generating figure
            f = figure();
            f.Visible = false;
            n = ceil(sqrt(app.slices.num)); % getting subplot 
            hold on
            
            % waitbar
            loadbar = app.slices.num;
            w = waitbar(0,'Loading Data');
            w.HandleVisibility = 'off';
            
            % plotting in loop
            for i = 1:app.slices.num
                filei = load(app.slices.files{i});
                
                subplot(n,n,i)
                imshow(filei.cmplx(:,:,slice,echo),[])
                titlename = ['Patient ' num2str(app.slices.list(i))];
                title(titlename);
                
                if(isvalid(w))
                    waitbar(i/loadbar,w);
                end
            end
            % overall title
            sgtitle(['Slice = ' num2str(slice) ', Echo = ' num2str(echo)]);
            
            % closing waitbar
            if(isvalid(w))
                close(w);
            end
            
            f.Visible = true;
            
            toggleVis(app,1) % bring back ability to change values
        end

        % Button pushed function: PreROIButton
        function PreROIButtonButtonPushed(app, event)
            getdir = uigetdir();
            
            if (~isempty(getdir))
                if(getdir == 0)
                    % do nothing
                elseif(~isempty(dir(([getdir '/*ROI*.mat'])))) % checking to see if the folder has the .mat files inside (of the LWI data)
                    app.preROIdir.Value = getdir; % saving the folder path
                    
                    if ~isempty(app.patT2data)
                        plotPreROI(app);
                    end
                    
                else
                    
                    m = msgbox('The folder selected must have .mat files containing the Patient ROIs.');
                    m.Visible = 'off';
                    pause(0.1)
                    m.Visible = 'on';
                end
            end
        end

        % Value changed function: VisROICheckBox
        function VisROICheckBoxValueChanged(app, event)
            updateFig(app,app.VisROICheckBox.Value)
        end

        % Button pushed function: LoadPrevButton
        function LoadPrevButtonPushed(app, event)
            [file,path] = uigetfile('*mat');
            
            if (~isempty(file))
                if(file == 0)
                    % do nothing
                else
                   roiPolys = load([path '/' file], 'roiPolys');
                   
                   if ~isempty(roiPolys)
                        roiPolys = roiPolys.roiPolys;
                        
                        for i = 1:numel(roiPolys.Data)
                        
                            app.roiData{end+1} = images.roi.Polygon(app.UIAxes,'Position',roiPolys.Data{i}.Position,'LineWidth',1,'MarkerSize',3.5,'InteractionsAllowed','all');
                            app.roiData{end}.Visible = 'off';
                            
                            % New addition to the table
                            roiName = strsplit(roiPolys.Names{i},{' #','_'});
                            nameofROI = roiName{1};
                            % Getting current names
                            if ~isempty(app.UITable.Data)
                                curRoiNames = app.UITable.Data.Var2; % getting current names in table
                                lastOne = sort(curRoiNames(contains(curRoiNames,nameofROI))); % which is the most latest ROI
                                if ~isempty(lastOne)
                                    laststrnum = strsplit(lastOne{end},'#'); lastnum = str2double(laststrnum{end});
                                    nameofROI = [nameofROI ' #' num2str(lastnum+1)]; % adding name
                                else
                                    nameofROI = [nameofROI ' #1']; % adding name
                                end
                            else
                                nameofROI = [nameofROI ' #1']; % adding name
                            end
                            
                            
                            ROIstate = logical([0 0 0 0]);
                            if length(roiName)>2
                                switch roiName{3}
                                    case 'Unassigned'
                                        ROIstate = logical([0 0 0 0]);
                                    case 'ProstateRoi'
                                        ROIstate = logical([1 0 0 0]);
                                    case 'PZ'
                                        ROIstate = logical([0 1 0 0]);
                                    case 'TZ'
                                        ROIstate = logical([0 0 1 0]);
                                    case 'CZ'
                                        ROIstate = logical([0 0 0 1]);
                                end
                            end
                            
                            if isempty(app.UITable.Data) % adding first file
                                app.UITable.Data = table(false, {nameofROI},ROIstate(1),ROIstate(2),ROIstate(3),ROIstate(4));
                            else % adding entries when some are already present
                                app.UITable.Data = table([app.UITable.Data.Var1; false],[app.UITable.Data.Var2; {nameofROI}],[app.UITable.Data.Var3; ROIstate(1)],[app.UITable.Data.Var4; ROIstate(2)],[app.UITable.Data.Var5; ROIstate(3)],[app.UITable.Data.Var6; ROIstate(4)]);
                            end
                            
                            % Colouring the ROI
                            if ~isempty(find(ROIstate))
                                app.roiData{end}.Color = app.roiColour{find(ROIstate)+1}; % colour based on click of zone
                            else
                                app.roiData{end}.Color = app.roiColour{1}; % none colour
                            end  
                            
                            % Toggling button/app visibility for drawing ROI
                            toggleVisDrawingROI(app,0); 
                            toggleVis(app,1);
                        end
                        
                        % moveable or not
                        updateMoveable(app);                   
                       
                       
                   else
                       errordlg('Need to select the .mat file that contains ROI polygon data i.e. "roiPoly"')
                       
                   end
                end
            end
        end

        % Button pushed function: ToggleAllROIVisButton
        function ToggleAllROIVisButtonPushed(app, event)
            if ~isempty(app.UITable.Data)
                if sum((app.UITable.Data.Var1)) >= 1
                    app.UITable.Data.Var1 = false(numel(app.UITable.Data.Var1),1); % turning all visible ROIs off (table edit)
                    for i = 1:numel(app.UITable.Data.Var1) % physically turning off each ROI
                        app.roiData{i}.Visible = false;
                    end
                else
                    app.UITable.Data.Var1 = true(numel(app.UITable.Data.Var1),1); % turning all visible ROIs on (table edit)
                    for i = 1:numel(app.UITable.Data.Var1) % physically turning on each ROI
                        app.roiData{i}.Visible = true;
                    end
                end
            end
            
        end

        % Button pushed function: FormatROIsButton
        function FormatROIsButtonPushed(app, event)
            % to shape all sub-ROIs such that the ROI exists within the
            % "overall" ROI. Sub-ROIs include PZ, TZ, and CZ. If none is
            % specified, it will not be formatted to be reduced to fit in
            % the "Overall" ROI (i.e. 'O' checked)
            if ~isempty(app.UITable.Data)
                overallROIs = find(app.UITable.Data.Var3);
                
                % Initializing for deleting
                deleteNoOverlap = [];
                
                % looping through all overall ROIs
                for i = 1:numel(overallROIs)
                    curNameOverallROI = app.UITable.Data.Var2{overallROIs(i)};
                    
                    % Getting the file name to save the ROI as
                    splitName = strsplit(app.UITable.Data.Var2{overallROIs(i)},{' #'});
                    specificROIs = find(app.UITable.Data.Var4 | app.UITable.Data.Var5 | app.UITable.Data.Var6);


                    % looping through all sub-ROIs
                    for j = 1:numel(specificROIs)  
                        % checking their names to be able to get the same
                        % slice for the overall and sub-ROI
                        if contains(app.UITable.Data.Var2{specificROIs(j)},splitName{1})
                            % calculating the overlap, and reassigning to
                            % the specific ROI to be a subset of the
                            % overall ROI
                            p1 = polyshape(app.roiData{specificROIs(j)}.Position);
                            p2 = polyshape(app.roiData{overallROIs(i)}.Position);
                            p0 = intersect(p1,p2,'KeepCollinearPoints',false);
                            newVerts = p0.Vertices;
                            if p0.NumRegions
                                %checking for errors (nans / more than 1
                                %overlap)
                                if sum(isnan(newVerts(:,1)))
                                    newVerts = newVerts(find(~isnan(newVerts(:,1))),:); % removing nans
                                    % output warning dialog
                                    if p0.NumRegions >1
                                        warndlg(['ROI: ' splitName{1} ' may have its overlap incorrectly calculated since there are 2 or more regions present intersecting. There can only be 1 region.'],'Warning on ROI');
                                    end
                                end
                                
                                % rewriting the subROI
                                app.roiData{specificROIs(j)}.Position = newVerts; 
                                
                            else
                                deleteNoOverlap(end+1) = specificROIs(j);
                            end
                        end
                    end
                end
                
                % Deleting if no overlapping subROI
                % supressing warning
                warning('off', 'MATLAB:polyshape:repairedBySimplify')
                for k = 1:length(deleteNoOverlap)
                    delRoi = sort(deleteNoOverlap,'descend');
                    app.roiData{delRoi(k)}.Visible = false; % removing visibility
                    delete(app.roiData{delRoi(k)}); % removing handle
                    app.roiData(delRoi(k)) = []; % removing from roiData dataset
                    app.UITable.Data(delRoi(k),:) = []; % removing from table
                end
                % updating currently selected
                app.curTableInd = [];
            end
        end

        % Value changed function: MoveableCheckBox
        function MoveableCheckBoxValueChanged(app, event)
            % function to allow ROIs to be moveable or not
            updateMoveable(app);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            % allows users to flip to a certain slice from 1 - 10 using
            % keyboard (advance in slices by pressing space)
            % or turn on visibility by pressing v
                        
            % for when drawing, there is added functionality
            if ~(isempty(app.imData)) 
                key = event.Key;
                
                % A and D key goes though echoes
                if key == "a"
                    val = round(app.Slider.Value) -1;
            
                    if val < app.Slider.Limits(1)
                        val = app.Slider.Limits(end); % looping if at the last slice
                    end
                    
                    app.EchoSelectedEditField.Value = val; % update the echo selected field
                    app.Slider.Value = val; % rounding slider value 
                    
                    
                    % update figure
                    if ~isempty(app.imData)
                        updateFig(app, app.VisROICheckBox.Value) % update figure
                    end
                
                elseif key == "d"
                    val = round(app.Slider.Value) +1;
                    
                    if val > app.Slider.Limits(end)
                        val = app.Slider.Limits(1); % looping if at the last slice
                    end
                    
                    app.EchoSelectedEditField.Value = val; % update the echo selected field
                    app.Slider.Value = val; % rounding slider value 
                    
                    % update figure
                    if ~isempty(app.imData)
                        updateFig(app, app.VisROICheckBox.Value) % update figure
                    end
                        
                elseif key == 'v'
                    % switch visibility of vis ROI
                    app.VisROICheckBox.Value = ~app.VisROICheckBox.Value;
                    updateFig(app,app.VisROICheckBox.Value) 
                
                elseif key == "m"
                    % switch moveability of ROIs
                    app.MoveableCheckBox.Value = ~app.MoveableCheckBox.Value;
                    updateMoveable(app)
                    
                elseif key == "q" || key == "w" || key == "e" || key == "r" 
                    % visibility of ROIs 
                    roiVisible(app,key)
                end
                
                % Checking to make sure that we're not interrupting any process
                if (app.FinishROIButton.Enable == 0)
                    
                    if key == "rightarrow" || key == "space"
                        %advance one slice
                        val = round(app.SliceSlider.Value)+1; % adding one slice
                        
                        if val > app.SliceSlider.Limits(end)
                            val = app.SliceSlider.Limits(1); % looping if at the last slice
                        end
                        
                        app.SliceSelectedEditField.Value = val; % update the echo selected field
                        app.SliceSlider.Value = val; % rounding slider value 
                        
                        % update figure
                        if ~isempty(app.imData)
                            updateFig(app,app.VisROICheckBox.Value) % update figure
                        end
                            
                    elseif key == "leftarrow"
                        %advance one slice
                        val = round(app.SliceSlider.Value)-1; % adding one slice
                        
                        if val < app.SliceSlider.Limits(1)
                            val = app.SliceSlider.Limits(end); % looping if at the last slice
                        end
                        
                        app.SliceSelectedEditField.Value = val; % update the echo selected field
                        app.SliceSlider.Value = val; % rounding slider value 
                        
                        % update figure
                        if ~isempty(app.imData)
                            updateFig(app,app.VisROICheckBox.Value) % update figure
                        end   
                        
                    
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            qans = questdlg('Close the app?','Question Dialog','Yes','No','No');
        
            switch qans
                case 'Yes'
                    % delete app
                    delete(app)
                case 'No'
                    % do nothing
            end
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.T2DataFolderButton = uibutton(app.UIFigure, 'push');
            app.T2DataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @T2DataFolderButtonPushed, true);
            app.T2DataFolderButton.Text = 'T2 Data Folder';
            app.T2datadir = uieditfield(app.UIFigure, 'text');
            app.T2datadir.Editable = 'off';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10];
            app.Slider.Enable = 'off';
            app.ReInitializeGUIButton = uibutton(app.UIFigure, 'push');
            app.ReInitializeGUIButton.ButtonPushedFcn = createCallbackFcn(app, @ReInitializeGUIButtonPushed, true);
            app.ReInitializeGUIButton.Text = 'Re-Initialize GUI';
            app.EchoSelectedLabel = uilabel(app.UIFigure);
            app.EchoSelectedLabel.Text = {'Echo '; 'Selected'};
            app.EchoSelectedEditField = uieditfield(app.UIFigure, 'numeric');
            app.EchoSelectedEditField.RoundFractionalValues = 'on';
            app.EchoSelectedEditField.ValueChangedFcn = createCallbackFcn(app, @EchoSelectedEditFieldValueChanged, true);
            app.EchoSelectedEditField.Enable = 'off';
            app.EchoSelectedEditField.Value = 1;
            app.debugtogglevisButton = uibutton(app.UIFigure, 'push');
            app.debugtogglevisButton.ButtonPushedFcn = createCallbackFcn(app, @debugtogglevisButtonPushed, true);
            app.debugtogglevisButton.Text = {'debug: '; 'toggle vis'};
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.Limits = [0 10];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10];
            app.SliceSlider.Enable = 'off';
            app.SliceSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.SliceSelectedEditFieldLabel.Text = {'Slice'; 'Selected'};
            app.SliceSelectedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SliceSelectedEditField.RoundFractionalValues = 'on';
            app.SliceSelectedEditField.Enable = 'off';
            app.SliceSelectedEditField.Value = 1;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load Tab';
            app.ListBox = uilistbox(app.LoadTab);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.StartEchoEditFieldLabel = uilabel(app.LoadTab);
            app.StartEchoEditFieldLabel.Text = 'Start Echo';
            app.EchoStart = uieditfield(app.LoadTab, 'numeric');
            app.EchoStart.RoundFractionalValues = 'on';
            app.EchoStart.Enable = 'off';
            app.EchoStart.Value = 1;
            app.EndEchoLabel = uilabel(app.LoadTab);
            app.EndEchoLabel.Text = 'End Echo';
            app.EchoEnd = uieditfield(app.LoadTab, 'numeric');
            app.EchoEnd.RoundFractionalValues = 'on';
            app.EchoEnd.Enable = 'off';
            app.EchoEnd.Value = 1;
            app.LoadPatBut = uibutton(app.LoadTab, 'push');
            app.LoadPatBut.ButtonPushedFcn = createCallbackFcn(app, @LoadPatButPushed, true);
            app.LoadPatBut.Enable = 'off';
            app.LoadPatBut.Text = 'Load Patient Image';
            app.FilterImagesLoadButton = uibutton(app.LoadTab, 'push');
            app.FilterImagesLoadButton.ButtonPushedFcn = createCallbackFcn(app, @FilterImagesLoadButtonPushed, true);
            app.FilterImagesLoadButton.Enable = 'off';
            app.FilterImagesLoadButton.Text = {'Filter Images '; '(Load)'};
            app.STDofFiltEditField = uieditfield(app.LoadTab, 'numeric');
            app.STDofFiltEditField.Enable = 'off';
            app.STDofFiltEditField.Value = 10;
            app.STDofFiltEditFieldLabel = uilabel(app.LoadTab);
            app.STDofFiltEditFieldLabel.Enable = 'off';
            app.STDofFiltEditFieldLabel.Text = 'STD of Filt';
            app.PatNumEditField = uieditfield(app.LoadTab, 'numeric');
            app.PatNumEditField.Editable = 'off';
            app.DrawROITab = uitab(app.TabGroup);
            app.DrawROITab.Title = 'Draw ROI';
            app.DrawROIButton = uibutton(app.DrawROITab, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Enable = 'off';
            app.DrawROIButton.Text = 'Draw ROI';
            app.RemoveROIButton = uibutton(app.DrawROITab, 'push');
            app.RemoveROIButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveROIButtonPushed, true);
            app.RemoveROIButton.Enable = 'off';
            app.RemoveROIButton.Text = 'Remove ROI';
            app.FinishROIButton = uibutton(app.DrawROITab, 'push');
            app.FinishROIButton.ButtonPushedFcn = createCallbackFcn(app, @FinishROIButtonPushed, true);
            app.FinishROIButton.Enable = 'off';
            app.FinishROIButton.Text = 'Finish ROI';
            app.CancelButton = uibutton(app.DrawROITab, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Visible = 'off';
            app.CancelButton.Text = 'Cancel ';
            app.UITable = uitable(app.DrawROITab);
            app.UITable.ColumnName = {'Vis?'; 'Name'; 'O'; 'PZ'; 'TZ'; 'CZ'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true true true true true];
            app.UITable.ColumnEditable = [true false true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SaveROIsButton = uibutton(app.DrawROITab, 'push');
            app.SaveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIsButtonPushed, true);
            app.SaveROIsButton.Enable = 'off';
            app.SaveROIsButton.Text = 'Save ROIs';
            app.VisROICheckBox = uicheckbox(app.DrawROITab);
            app.VisROICheckBox.ValueChangedFcn = createCallbackFcn(app, @VisROICheckBoxValueChanged, true);
            app.VisROICheckBox.Text = 'Vis ROI?';
            app.VisROICheckBox.Value = true;
            app.LoadPrevButton = uibutton(app.DrawROITab, 'push');
            app.LoadPrevButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPrevButtonPushed, true);
            app.LoadPrevButton.Enable = 'off';
            app.LoadPrevButton.Text = {'Load'; 'Prev'};
            app.ToggleAllROIVisButton = uibutton(app.DrawROITab, 'push');
            app.ToggleAllROIVisButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleAllROIVisButtonPushed, true);
            app.ToggleAllROIVisButton.Enable = 'off';
            app.ToggleAllROIVisButton.Text = 'Toggle All ROI Vis';
            app.FormatROIsButton = uibutton(app.DrawROITab, 'push');
            app.FormatROIsButton.ButtonPushedFcn = createCallbackFcn(app, @FormatROIsButtonPushed, true);
            app.FormatROIsButton.Enable = 'off';
            app.FormatROIsButton.Text = {'Format '; 'ROIs'};
            app.MoveableCheckBox = uicheckbox(app.DrawROITab);
            app.MoveableCheckBox.ValueChangedFcn = createCallbackFcn(app, @MoveableCheckBoxValueChanged, true);
            app.MoveableCheckBox.Text = 'Moveable?';
            app.MoveableCheckBox.Value = true;
            app.testbuttonButton = uibutton(app.UIFigure, 'push');
            app.testbuttonButton.ButtonPushedFcn = createCallbackFcn(app, @testbuttonButtonPushed, true);
            app.testbuttonButton.Text = {'test '; 'button'};
            app.PlotAllExternallyButton = uibutton(app.UIFigure, 'push');
            app.PlotAllExternallyButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllExternallyButtonPushed, true);
            app.PlotAllExternallyButton.Text = 'Plot All (Externally) ';
            app.PreROIButton = uibutton(app.UIFigure, 'push');
            app.PreROIButton.ButtonPushedFcn = createCallbackFcn(app, @PreROIButtonButtonPushed, true);
            app.PreROIButton.Text = '(pre) ROI Folder';
            app.preROIdir = uieditfield(app.UIFigure, 'text');
            app.preROIdir.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = draw_rois_gui_v6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end