classdef fitROIsGUI_processAfter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ResizeFiguresButton      matlab.ui.control.Button
        CancelnoroiButton        matlab.ui.control.Button
        weightingSwitch          matlab.ui.control.ToggleSwitch
        weightingSwitchLabel     matlab.ui.control.Label
        HaveanoldROIButton       matlab.ui.control.Button
        WeightingEditField       matlab.ui.control.NumericEditField
        WeightingEditFieldLabel  matlab.ui.control.Label
        t2w_slice                matlab.ui.control.NumericEditField
        ResetImagesButton        matlab.ui.control.Button
        SliceSlider              matlab.ui.control.Slider
        SlicesliderSliderLabel   matlab.ui.control.Label
        CloseAppButton           matlab.ui.control.Button
        MapROIButton             matlab.ui.control.Button
        lwi_echo                 matlab.ui.control.NumericEditField
        EchoSlider               matlab.ui.control.Slider
        EchoSliderLabel          matlab.ui.control.Label
        ax_paramIm               matlab.ui.control.UIAxes
        ax_weighted_HR           matlab.ui.control.UIAxes
        ax_LWI_roi               matlab.ui.control.UIAxes
        ax_LWI                   matlab.ui.control.UIAxes
        ax_deformed              matlab.ui.control.UIAxes
        ax_T2W                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ims_t2w % the 512x12 t2w images for a certain patient and all slices
        ims_t2echoes % the 240x240 t2w images for a certain patient and all slices and echoes
        im_weightedLWI % weighted LWI
        snum % slice number
        pnum % patient number

        weightingVal
    
        % ROIs for LWI (240x240)
        roi_pz_lwi % 
        roi_tz_lwi % 
        roi_full_lwi % 
        roi_lwi
        roi_outline
        
        % ROIs that are new and drawn
        roi_t2w
        roi_out %the one that's sent to python
        dimSmall  
        dimLarge  
        dimResize % dimensions sent out to python

        % boundaries of ROIs
        b_lwi
        b_t2w
        b_t2w_pos

        % for overlay and weighting
        overlayIm 
        weight
        outputHR
    end


    methods (Access = private)
        
        %------------
        % Function to update images
        %------------
        function updateImages(app)
            disableButts(app)
    %                 imagesc(app.ax_LWI,app.ims_t2echoes(:,:,app.snum,app.lwi_echo.Value))
    %                 imagesc(app.ax_T2W,app.ims_t2w(:,:,app.t2w_slice.Value))
            
            imshow(app.ims_t2echoes(:,:,app.snum,app.lwi_echo.Value), [],'Parent',app.ax_LWI);
            disableDefaultInteractivity(app.ax_LWI)
            
            overlayedIm = app.ims_t2echoes(:,:,app.snum,app.lwi_echo.Value);
            overlayedIm(logical(app.roi_outline)) = 1;
            imshow(overlayedIm, [],'Parent',app.ax_LWI_roi);

            disableDefaultInteractivity(app.ax_LWI_roi)
            imshow(app.ims_t2w(:,:,app.t2w_slice.Value),[],'Parent', app.ax_T2W);
            disableDefaultInteractivity(app.ax_T2W)
    
            app.ax_T2W.Title.String = ['High Res T2W , Slice: ' num2str(app.t2w_slice.Value) ' (512x512)'];
            app.ax_LWI.Title.String = ['LWI Image Patient: ' num2str(app.pnum) ', Slice: ' num2str(app.snum) ', Echo: ' num2str(app.lwi_echo. ...
                Value) ' (240x240)'];


            % Place ROIs
            drawLWIroi(app);

            enableButts(app)
        end
        
        %------------
        % Disable buttons and sliders
        %------------
        function disableButts(app)
            app.ResetImagesButton.Enable = false;
            app.MapROIButton.Enable = false;
            app.CloseAppButton.Enable = false;
            app.SliceSlider.Enable = false;
            app.EchoSlider.Enable = false;
        end
        
        %------------
        % Enable buttons and sliders
        %------------
        function enableButts(app)
            app.ResetImagesButton.Enable = true;
            app.MapROIButton.Enable = true;
            app.CloseAppButton.Enable = true;
            app.SliceSlider.Enable = true;
            app.EchoSlider.Enable = true;
    
        end


        %------------
        % Draw the T2W rois
        %------------
        function drawT2Wroi(app)
            if isempty(app.b_t2w_pos)
                rx1 = min(app.b_lwi(:,1));
                rx2 = max(app.b_lwi(:,1));
                ry1 = min(app.b_lwi(:,2));
                ry2 = max(app.b_lwi(:,2));
                % New ROI on first image
                app.b_t2w = images.roi.Rectangle(app.ax_T2W,'Position',[rx1,ry1,rx2-rx1,ry2-ry1],'InteractionsAllowed','all');
            else
                app.b_t2w = images.roi.Rectangle(app.ax_T2W,'Position',[app.b_t2w_pos(1),app.b_t2w_pos(2),app.b_t2w_pos(3),app.b_t2w_pos(4)],'InteractionsAllowed','all');
            end
        end

        %------------
        % Draw the LWI rois
        %------------
        function drawLWIroi(app)
            %initial ROI in LWI data
            app.b_lwi = bwboundaries(flip(imrotate(app.roi_lwi,90),1)); app.b_lwi = app.b_lwi{1};
            % B1_roi = bwboundaries(roi_pz); B1_roi = B1_roi{1};
            rx1 = min(app.b_lwi(:,1));
            rx2 = max(app.b_lwi(:,1));
            ry1 = min(app.b_lwi(:,2));
            ry2 = max(app.b_lwi(:,2));
            images.roi.Rectangle(app.ax_LWI,'Position',[rx1,ry1,rx2-rx1,ry2-ry1],'InteractionsAllowed','None');
        end

        %------------
        % Normalize
        %------------
        function [Mnorm] = normalize1(app, M, normRange)
            % Inputs:
            %  M:          matrix of size [n,m]
            %  range:      range to max/min
            % Return:
            %  Mnorm: normalized matrix of size [n,m]
            Mcopy = M(~isnan(M));
            Mnorm = (M - min(Mcopy)) / (max(Mcopy) - min(Mcopy));

            if length(normRange)>1
                Mnorm = Mnorm*(normRange(2) - normRange(1)) + normRange(1);
            end
        end

        %------------
        % Tanh
        %------------
        function [t] = tanh1(app, x)
            % Inputs:
            %  x:    matrix of size [n,m]
            %  
            % Return:
            %  t:    tanh of [n,m]
            
            t = tanh(x);
        end
        
        %------------
        % Standardize
        %------------
        function [Mstd] = standardize1(app, M)
            % Inputs:
            %  M:          matrix of size [n,m]
            %  
            % Return:
            %  Mstd: normalized matrix of size [n,m]
            
            Mcopy = M(~isnan(M));
            Mstd = (M - mean(Mcopy)) / std(Mcopy);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pNum, sNum, roi_lwi_zone, overlayIm)
            % #######################################
            % ## Getting app values and constants
            % #######################################
            % ------
            % Defining Folder Locations
            % ------
            t2w_folder =         'C:\Users\candi\Documents\Research\1 LWI Project\1 Data\4 HighRes\High Res Mat';
            t2echoes_folder =      'C:\Users\candi\Documents\Research\1 LWI Project\1 Data\3 T2 Decay';
            folderPZml =         'C:\Users\candi\Documents\Research\1 LWI Project\1 Data\2 ROIs\CombinedROIs\PZ';
            folderFullml =       'C:\Users\candi\Documents\Research\1 LWI Project\1 Data\2 ROIs\CombinedROIs\Full';
            
            % ------
            % Filling constant values
            % ------
            app.roi_lwi = roi_lwi_zone;
            if isstr(sNum)
                app.snum = str2num(sNum);
            else
                app.snum = sNum;
            end
            if isstr(pNum)
                app.pnum = str2num(pNum);
            else
                app.pnum = pNum;
            end
            app.overlayIm = overlayIm; % LWF or PCA image
            app.weightingVal = app.WeightingEditField.Value;
        
            % ------
            % Obtaining files from folders
            % ------
            % loading the high-res t2w images (as slices)
            t2w = load(strjoin({t2w_folder, ['P' num2str(app.pnum)]},'\'));
            app.ax_T2W.Title.String = ['High Res T2W , Slice: ' num2str(app.t2w_slice.Value) ' (512x512)'];

            % loading the t2 echo train images
            t2echoesdir = dir(strjoin({t2echoes_folder, ['P*' num2str(app.pnum) '_cmplx.mat']},'\'));
            t2echoes = load(strjoin({t2echoesdir.folder, t2echoesdir.name},'\'));
            app.ax_LWI.Title.String = ['LWI Image Patient: ' num2str(app.pnum) ', Slice: ' num2str(app.snum) ', Echo: ' num2str(app.lwi_echo. ...
                Value) ' (240x240)'];
            
            % putting them into app properties
            app.ims_t2w = t2w.T2W;  
            app.ims_t2echoes = t2echoes.cmplx;
            
            % getting LWI roi outline on small image
            SE = strel('disk',1,6);
            app.roi_outline = imdilate(app.roi_lwi,SE) - imerode(app.roi_lwi,SE);

            % #######################################
            % ## Loading info for graphs and whatnot
            % #######################################
            % updating slices and echoes
            app.lwi_echo.Value = app.EchoSlider.Value;
            app.t2w_slice.Value = app.SliceSlider.Value;
            updateImages(app);
            drawT2Wroi(app);


        end

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            app.weightingSwitch.Value = 'On';
            app.weightingSwitchValueChanged(app);
            if isempty(app.roi_out)
                MapROIButtonPushed(app);
            else
                app.UIFigure.UserData = struct("outputHR", app.outputHR, "weight", app.WeightingEditField.Value,"t2w_roi",app.roi_out,"t2w_slice",app.t2w_slice.Value,...
                    "dimSmall",app.dimSmall, "dimLarge", app.dimLarge, "dimResize", app.dimResize);
            end
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            value = round(app.SliceSlider.Value);

            app.SliceSlider.Value = value; % rounding the value
            app.t2w_slice.Value = value; % updating the t2w slice value window


        end

        % Value changed function: EchoSlider
        function EchoSliderValueChanged(app, event)
            value = round(app.EchoSlider.Value);

            app.EchoSlider.Value = value; % rounding the value
            app.lwi_echo.Value = value; % updating the t2w slice value window
        end

        % Button pushed function: ResetImagesButton
        function ResetImagesButtonPushed(app, event)
            app.b_t2w_pos = [app.b_t2w.Position(1),app.b_t2w.Position(2),app.b_t2w.Position(3),app.b_t2w.Position(4)];
            updateImages(app);
            drawT2Wroi(app);
            if ~isempty(app.roi_out)
                MapROIButtonPushed(app);
            end
        end

        % Button pushed function: MapROIButton
        function MapROIButtonPushed(app, event)
            % new ROI 'n' // maths
            rx1 = min(app.b_lwi(:,1));
            rx2 = max(app.b_lwi(:,1));
            ry1 = min(app.b_lwi(:,2));
            ry2 = max(app.b_lwi(:,2));
            nx1 = app.b_t2w.Position(1);
            nx2 = app.b_t2w.Position(1) + app.b_t2w.Position(3);
            ny1 = app.b_t2w.Position(2);
            ny2 = app.b_t2w.Position(2) + app.b_t2w.Position(4);
            scalex = ((nx2-nx1) - (rx2-rx1)) / (rx2-rx1);
            scaley = ((ny2-ny1) - (ry2-ry1)) / (ry2-ry1);
            subroi = app.roi_lwi(ry1:ry2,rx1:rx2);
            rescale = ([size(subroi,1)*(1+scaley) size(subroi,2)*(1+scalex)]);
            ssubroi = imresize(subroi,rescale);
            
            newRectPos = app.b_t2w.Position;
            
            imshow(app.ax_T2W.Children(2).CData,[],'Parent',app.ax_deformed);
            rs_roi = zeros(size(app.ax_T2W.Children(2).CData));
            
            [lw,lh] = size(ssubroi);
            xbar = round((nx2-nx1)/2+nx1);
            ybar = round((ny2-ny1)/2+ny1);
            rs_roi(round(ybar-lw/2):round(ybar+lw/2)-1,round(xbar-lh/2):round(xbar+lh/2)-1) = ssubroi;
            rs_roi = rs_roi>0.05; % new ROI
            app.roi_out = rs_roi; % the ROI that goes out is saved in app.roi_out
            
            SE = strel('disk',1,6);
            outline = imdilate(rs_roi,SE) - imerode(rs_roi,SE);
            overlayedIm = app.ax_T2W.Children(2).CData;
            overlayedIm(logical(outline)) = 1;
            imshow(overlayedIm, [],'Parent',app.ax_deformed);        
            disableDefaultInteractivity(app.ax_deformed);

            % Data to save and send out to python
            smallPos.x1 = ry1;
            smallPos.x2 = ry2;
            smallPos.y1 = rx1;
            smallPos.y2 = rx2;
            bb = bwboundaries(rs_roi); bb = bb{1};
            largePos.x1 = min(bb(:,1));
            largePos.x2 = max(bb(:,1));
            largePos.y1 = min(bb(:,2));
            largePos.y2 = max(bb(:,2));
            app.dimSmall = smallPos;
            app.dimLarge = largePos;
            app.dimResize= rescale;

            % -- performing weighting
            hr_img = app.ax_T2W.Children(2).CData;
            hr_roi = rs_roi;
            tempIm_hr = hr_img;
            tempIm_hr = (tempIm_hr - min(hr_img(hr_roi)))/(max(hr_img(hr_roi))-min(hr_img(hr_roi)));
            ssub_im = imresize(app.overlayIm(ry1:ry2, rx1:rx2), rescale, 'AntiAliasing',true);
            clippedROIt2w = hr_roi(min(bb(:,1)):max(bb(:,1)),min(bb(:,2)):max(bb(:,2)));
            
            
            outline = imdilate(clippedROIt2w,SE) - imerode(clippedROIt2w,SE);
            showssub_im = ssub_im;
            showssub_im(logical(outline)) = 1;
            imshow(showssub_im, [],'Parent',app.ax_paramIm);
            app.ax_paramIm.Title.String = ['Weighted Im: ' num2str(app.WeightingEditField.Value) ' (512x512)'];    
            disableDefaultInteractivity(app.ax_paramIm);
            ssub_im(~clippedROIt2w)= NaN;

            hr_w_tstand = normalize1(app,hr_img,0);
            w_temp_hr_sub = tanh1(app,standardize1(app,ssub_im));
            new_w_temp = tempIm_hr(min(bb(:,1)):max(bb(:,1)),min(bb(:,2)):max(bb(:,2)))*(1-app.weightingVal) + normalize1(app,tempIm_hr(min(bb(:,1)):max(bb(:,1)),min(bb(:,2)):max(bb(:,2))).*w_temp_hr_sub,0)*app.weightingVal;
            new_w_temp = normalize1(app,new_w_temp, [min(hr_w_tstand(hr_roi)),max(hr_w_tstand(hr_roi))]);
            hr_overlay_sub = hr_w_tstand(min(bb(:,1)):max(bb(:,1)),min(bb(:,2)):max(bb(:,2)));
            hr_overlay_sub(clippedROIt2w) = new_w_temp(clippedROIt2w);
            hr_w_tstand(min(bb(:,1)):max(bb(:,1)),min(bb(:,2)):max(bb(:,2))) = hr_overlay_sub;
            imshow(hr_w_tstand, [],'Parent',app.ax_weighted_HR);
            disableDefaultInteractivity(app.ax_weighted_HR);

            app.outputHR = hr_w_tstand;
             

        end

        % Button pushed function: HaveanoldROIButton
        function HaveanoldROIButtonPushed(app, event)
            [fname,pname] = uigetfile('*.png', "Select saved ROI");

            if fname == 0

            else
                oldROI = imread([pname '/' fname]);

                if contains(fname,"slice_")
                    splits = strsplit(fname,{'slice_','.'});
                    newHRframe = str2num(splits{2});
                    app.SliceSlider.Value = newHRframe;
                    SliceSliderValueChanged(app);
                    ResetImagesButtonPushed(app);
                end
                
                if sum(size(app.ax_T2W.Children(2).CData) ==  size(oldROI)) == 2 % checking if they're the same size
                    bb = bwboundaries(oldROI); bb = bb{1};
                    app.b_t2w.Position(1) = min(bb(:,2));
                    app.b_t2w.Position(2) = min(bb(:,1));
                    app.b_t2w.Position(3) = max(bb(:,2))-min(bb(:,2));
                    app.b_t2w.Position(4) = max(bb(:,1))-min(bb(:,1));
                    MapROIButtonPushed(app);

                else
                    errordlg(["Loaded ROI does not have the same size [" str(size(oldROI,1)) " " str(size(oldROI,2)) "] as expected [" str(size(app.ims_t2w,1)) " " str(size(app.ims_t2w,2)) "]."]);
                end
            end
            % to put GUI on top
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

        end

        % Value changed function: weightingSwitch
        function weightingSwitchValueChanged(app, event)
            value = app.weightingSwitch.Value;
            if value == "On"
                app.weightingVal = app.WeightingEditField.Value;
            else
                app.weightingVal = 0;
            end
            MapROIButtonPushed(app)
        end

        % Button pushed function: CancelnoroiButton
        function CancelnoroiButtonPushed(app, event)
            app.UIFigure.UserData = struct('t2w_roi',[]);
        end

        % Button pushed function: ResizeFiguresButton
        function ResizeFiguresButtonPushed(app, event)
            rx1 = min(app.b_lwi(:,1));
            rx2 = max(app.b_lwi(:,1));
            ry1 = min(app.b_lwi(:,2));
            ry2 = max(app.b_lwi(:,2));
            images.roi.Rectangle(app.ax_LWI,'Position',[rx1,ry1,rx2-rx1,ry2-ry1],'InteractionsAllowed','None');
            
            r = 0.2;
            app.ax_LWI_roi.XAxis.Limits = [round(rx1*(1-r)) round(rx2*(1+r))];
            app.ax_LWI_roi.YAxis.Limits = [round(ry1*(1-r)) round(ry2*(1+r))];

            % map the roi 
            if ~isempty(app.roi_out)
                app.ax_T2W.XAxis.Limits = [round(app.dimLarge.y1*(1-r)) round(app.dimLarge.y2*(1+r))];
                app.ax_T2W.YAxis.Limits = [round(app.dimLarge.x1*(1-r)) round(app.dimLarge.x2*(1+r))];

                app.ax_deformed.XAxis.Limits = [round(app.dimLarge.y1*(1-r)) round(app.dimLarge.y2*(1+r))];
                app.ax_deformed.YAxis.Limits = [round(app.dimLarge.x1*(1-r)) round(app.dimLarge.x2*(1+r))];

                app.ax_weighted_HR.XAxis.Limits = [round(app.dimLarge.y1*(1-r)) round(app.dimLarge.y2*(1+r))];
                app.ax_weighted_HR.YAxis.Limits = [round(app.dimLarge.x1*(1-r)) round(app.dimLarge.x2*(1+r))];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_T2W = uiaxes(app.UIFigure);
            title(app.ax_T2W, 'High Res T2W (512x512)')
            app.ax_T2W.XTick = [];
            app.ax_T2W.YTick = [];
            app.ax_deformed = uiaxes(app.UIFigure);
            title(app.ax_deformed, 'Deformed ROI')
            app.ax_deformed.XTick = [];
            app.ax_deformed.YTick = [];
            app.ax_LWI = uiaxes(app.UIFigure);
            title(app.ax_LWI, 'T2 echo Image (240x240)')
            app.ax_LWI.XTick = [];
            app.ax_LWI.YTick = [];
            app.ax_LWI_roi = uiaxes(app.UIFigure);
            title(app.ax_LWI_roi, 'T2 echo ROI trace')
            app.ax_LWI_roi.XTick = [];
            app.ax_LWI_roi.YTick = [];
            app.ax_weighted_HR = uiaxes(app.UIFigure);
            title(app.ax_weighted_HR, 'Weighted High Res')
            app.ax_weighted_HR.XTick = [];
            app.ax_weighted_HR.YTick = [];
            app.ax_paramIm = uiaxes(app.UIFigure);
            title(app.ax_paramIm, 'Param Image')
            app.ax_paramIm.XTick = [];
            app.ax_paramIm.YTick = [];
            app.EchoSliderLabel = uilabel(app.UIFigure);
            app.EchoSliderLabel.Text = 'Echo Slider';
            app.EchoSlider = uislider(app.UIFigure);
            app.EchoSlider.Limits = [1 64];
            app.EchoSlider.ValueChangedFcn = createCallbackFcn(app, @EchoSliderValueChanged, true);
            app.EchoSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64];
            app.EchoSlider.Value = 4;
            app.lwi_echo = uieditfield(app.UIFigure, 'numeric');
            app.lwi_echo.Editable = 'off';
            app.lwi_echo.Value = 4;
            app.MapROIButton = uibutton(app.UIFigure, 'push');
            app.MapROIButton.ButtonPushedFcn = createCallbackFcn(app, @MapROIButtonPushed, true);
            app.MapROIButton.Text = 'Map ROI';
            app.CloseAppButton = uibutton(app.UIFigure, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            app.SlicesliderSliderLabel = uilabel(app.UIFigure);
            app.SlicesliderSliderLabel.Text = 'Slice slider';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.Limits = [1 18];
            app.SliceSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.MinorTicks = [];
            app.SliceSlider.Value = 12;
            app.ResetImagesButton = uibutton(app.UIFigure, 'push');
            app.ResetImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImagesButtonPushed, true);
            app.ResetImagesButton.Text = 'Reset Images';
            app.t2w_slice = uieditfield(app.UIFigure, 'numeric');
            app.t2w_slice.Editable = 'off';
            app.t2w_slice.Value = 12;
            app.WeightingEditFieldLabel = uilabel(app.UIFigure);
            app.WeightingEditFieldLabel.Text = 'Weighting';
            app.WeightingEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeightingEditField.Limits = [0 1];
            app.WeightingEditField.Value = 0.5;
            app.HaveanoldROIButton = uibutton(app.UIFigure, 'push');
            app.HaveanoldROIButton.ButtonPushedFcn = createCallbackFcn(app, @HaveanoldROIButtonPushed, true);
            app.HaveanoldROIButton.Text = 'Have an old ROI?';
            app.weightingSwitchLabel = uilabel(app.UIFigure);
            app.weightingSwitchLabel.Text = 'weighting';
            app.weightingSwitch = uiswitch(app.UIFigure, 'toggle');
            app.weightingSwitch.ValueChangedFcn = createCallbackFcn(app, @weightingSwitchValueChanged, true);
            app.CancelnoroiButton = uibutton(app.UIFigure, 'push');
            app.CancelnoroiButton.ButtonPushedFcn = createCallbackFcn(app, @CancelnoroiButtonPushed, true);
            app.CancelnoroiButton.Text = 'Cancel (no roi)';
            app.ResizeFiguresButton = uibutton(app.UIFigure, 'push');
            app.ResizeFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeFiguresButtonPushed, true);
            app.ResizeFiguresButton.Text = {'Resize '; 'Figures'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fitROIsGUI_processAfter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end